classdef appSimIC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure                   % UI Figure
        TabGroup                matlab.ui.container.TabGroup       % Sinal ...
        TabSignal               matlab.ui.container.Tab            % Sinal ...
        PanSinEntr              matlab.ui.container.Panel          % Sinal ...
        NumDistAlvo             matlab.ui.control.NumericEditField % [80 300]
        Label4                  matlab.ui.control.Label            % Ângulo...
        NumAngAlvo              matlab.ui.control.NumericEditField % [-180 ...
        Label5                  matlab.ui.control.Label            % Dist. ...
        NumDistRuido            matlab.ui.control.NumericEditField % [80 300]
        Label6                  matlab.ui.control.Label            % Ângulo...
        NumAngRuido             matlab.ui.control.NumericEditField % [-180 ...
        Label7                  matlab.ui.control.Label            % SNR [ ...
        NumSNR                  matlab.ui.control.NumericEditField % [-Inf ...
        Label8                  matlab.ui.control.Label            % Ruído
        DropRuido               matlab.ui.control.DropDown         % RB, Wh...
        Label42                 matlab.ui.control.Label            % Sinal
        DropSignal              matlab.ui.control.DropDown         % RB, Wh...
        Label3                  matlab.ui.control.Label            % Dist. ...
        axisSig                 matlab.ui.control.UIAxes          
        Panel9                  matlab.ui.container.Panel          % Panel
        ButPlaySinal            matlab.ui.control.Button           % Ouvir ...
        LabelLamp2              matlab.ui.control.Label            % Reprod...
        LampSignal              matlab.ui.control.Lamp            
        LabelKnob2              matlab.ui.control.Label            % Volume
        Knob2                   matlab.ui.control.Knob             % [0 100]
        ButPlayRuido            matlab.ui.control.Button           % Ouvir ...
        ButPlayMescla           matlab.ui.control.Button           % Ouvir ...
        ToggleSwitch            matlab.ui.control.ToggleSwitch     % Esquer...
        Label43                 matlab.ui.control.Label            % *
        Label44                 matlab.ui.control.Label            % *
        Label45                 matlab.ui.control.Label            % Pos. Alvo
        Label46                 matlab.ui.control.Label            % Pos. R...
        TabPaci                 matlab.ui.container.Tab            % Paciente
        PanPac                  matlab.ui.container.Panel          % Propri...
        ButImportPac            matlab.ui.control.Button           % Import...
        LabelDropDown           matlab.ui.control.Label            % Estrat...
        DropStrat               matlab.ui.control.DropDown         % ACE, CIS
        LabelNumericEditField2  matlab.ui.control.Label            % Num má...
        Num_maxima              matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField3  matlab.ui.control.Label            % Taxa d...
        Num_taxa_est            matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField4  matlab.ui.control.Label            % Largur...
        Num_largura_pulso1      matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField5  matlab.ui.control.Label            % IPG [ s ]
        Num_interphase_gap      matlab.ui.control.NumericEditField % [-Inf ...
        LabelDropDown2          matlab.ui.control.Label            % LGF
        DropLGF                 matlab.ui.control.DropDown         % 20, 30...
        Label                   matlab.ui.control.Label            % Num. c...
        Num_num_canais          matlab.ui.control.NumericEditField % [8 22]
        Panel8                  matlab.ui.container.Panel          % Propri...
        List_pacientes          matlab.ui.control.ListBox          % Item 1...
        TabMapeamento           matlab.ui.container.TabGroup       % T-Leve...
        Tab                     matlab.ui.container.Tab            % T-Level
        LabelNumericEditField   matlab.ui.control.Label            % 1
        Num_M11                 matlab.ui.control.NumericEditField % [-Inf ...
        Label20                 matlab.ui.control.Label            % 2
        Num_M21                 matlab.ui.control.NumericEditField % [-Inf ...
        Label21                 matlab.ui.control.Label            % 3
        Num_M31                 matlab.ui.control.NumericEditField % [-Inf ...
        Label22                 matlab.ui.control.Label            % 4
        Num_M41                 matlab.ui.control.NumericEditField % [-Inf ...
        Label23                 matlab.ui.control.Label            % 5
        Num_M51                 matlab.ui.control.NumericEditField % [-Inf ...
        Label24                 matlab.ui.control.Label            % 6
        Num_M61                 matlab.ui.control.NumericEditField % [-Inf ...
        Label25                 matlab.ui.control.Label            % 7
        Num_M71                 matlab.ui.control.NumericEditField % [-Inf ...
        Label26                 matlab.ui.control.Label            % 9
        Num_M91                 matlab.ui.control.NumericEditField % [-Inf ...
        Label27                 matlab.ui.control.Label            % 8
        Num_M81                 matlab.ui.control.NumericEditField % [-Inf ...
        Label28                 matlab.ui.control.Label            % 10
        Num_M101                matlab.ui.control.NumericEditField % [-Inf ...
        Label29                 matlab.ui.control.Label            % 11
        Num_M111                matlab.ui.control.NumericEditField % [-Inf ...
        Label30                 matlab.ui.control.Label            % 12
        Label31                 matlab.ui.control.Label            % 13
        Label32                 matlab.ui.control.Label            % 14
        Label33                 matlab.ui.control.Label            % 15
        Label34                 matlab.ui.control.Label            % 16
        Label35                 matlab.ui.control.Label            % 17
        Label36                 matlab.ui.control.Label            % 18
        Label37                 matlab.ui.control.Label            % 20
        Label38                 matlab.ui.control.Label            % 19
        Label39                 matlab.ui.control.Label            % 21
        Label40                 matlab.ui.control.Label            % 22
        Num_M121                matlab.ui.control.NumericEditField % [-Inf ...
        Num_M131                matlab.ui.control.NumericEditField % [-Inf ...
        Num_M141                matlab.ui.control.NumericEditField % [-Inf ...
        Num_M151                matlab.ui.control.NumericEditField % [-Inf ...
        Num_M161                matlab.ui.control.NumericEditField % [-Inf ...
        Num_M171                matlab.ui.control.NumericEditField % [-Inf ...
        Num_M181                matlab.ui.control.NumericEditField % [-Inf ...
        Num_M191                matlab.ui.control.NumericEditField % [-Inf ...
        Num_M201                matlab.ui.control.NumericEditField % [-Inf ...
        Num_M211                matlab.ui.control.NumericEditField % [-Inf ...
        Num_M221                matlab.ui.control.NumericEditField % [-Inf ...
        Tab2                    matlab.ui.container.Tab            % C-Level
        Num_M122                matlab.ui.control.NumericEditField % [-Inf ...
        Num_M132                matlab.ui.control.NumericEditField % [-Inf ...
        Num_M142                matlab.ui.control.NumericEditField % [-Inf ...
        Num_M152                matlab.ui.control.NumericEditField % [-Inf ...
        Num_M162                matlab.ui.control.NumericEditField % [-Inf ...
        Num_M172                matlab.ui.control.NumericEditField % [-Inf ...
        Num_M182                matlab.ui.control.NumericEditField % [-Inf ...
        Num_M192                matlab.ui.control.NumericEditField % [-Inf ...
        Num_M202                matlab.ui.control.NumericEditField % [-Inf ...
        Num_M212                matlab.ui.control.NumericEditField % [-Inf ...
        Num_M222                matlab.ui.control.NumericEditField % [-Inf ...
        Num_M12                 matlab.ui.control.NumericEditField % [-Inf ...
        Num_M22                 matlab.ui.control.NumericEditField % [-Inf ...
        Num_M32                 matlab.ui.control.NumericEditField % [-Inf ...
        Num_M42                 matlab.ui.control.NumericEditField % [-Inf ...
        Num_M52                 matlab.ui.control.NumericEditField % [-Inf ...
        Num_M62                 matlab.ui.control.NumericEditField % [-Inf ...
        Num_M72                 matlab.ui.control.NumericEditField % [-Inf ...
        Num_M82                 matlab.ui.control.NumericEditField % [-Inf ...
        Num_M92                 matlab.ui.control.NumericEditField % [-Inf ...
        Num_M102                matlab.ui.control.NumericEditField % [-Inf ...
        Num_M112                matlab.ui.control.NumericEditField % [-Inf ...
        Label48                 matlab.ui.control.Label            % 1
        Label49                 matlab.ui.control.Label            % 2
        Label50                 matlab.ui.control.Label            % 3
        Label51                 matlab.ui.control.Label            % 4
        Label52                 matlab.ui.control.Label            % 5
        Label53                 matlab.ui.control.Label            % 6
        Label54                 matlab.ui.control.Label            % 7
        Label55                 matlab.ui.control.Label            % 9
        Label56                 matlab.ui.control.Label            % 8
        Label57                 matlab.ui.control.Label            % 10
        Label58                 matlab.ui.control.Label            % 11
        Label59                 matlab.ui.control.Label            % 12
        Label60                 matlab.ui.control.Label            % 13
        Label61                 matlab.ui.control.Label            % 14
        Label62                 matlab.ui.control.Label            % 15
        Label63                 matlab.ui.control.Label            % 16
        Label64                 matlab.ui.control.Label            % 17
        Label65                 matlab.ui.control.Label            % 18
        Label66                 matlab.ui.control.Label            % 20
        Label67                 matlab.ui.control.Label            % 19
        Label68                 matlab.ui.control.Label            % 21
        Label69                 matlab.ui.control.Label            % 22
        TabProcess              matlab.ui.container.Tab            % Proces...
        PanProcess              matlab.ui.container.Panel          % Proces...
        Label9                  matlab.ui.control.Label            % Banco ...
        DropBancFilt            matlab.ui.control.DropDown         % ERB, N...
        Label10                 matlab.ui.control.Label            % Extr. ...
        DropExtrEnvolt          matlab.ui.control.DropDown         % Hilber...
        Label11                 matlab.ui.control.Label            % Freq. ...
        NumCutFPB               matlab.ui.control.NumericEditField % [-Inf ...
        Label12                 matlab.ui.control.Label            % Ordem FPB
        NumOrdFPB               matlab.ui.control.NumericEditField % [-Inf ...
        ButSimProcess           matlab.ui.control.Button           % Simula...
        LabelLamp               matlab.ui.control.Label            % Status
        LampProcess             matlab.ui.control.Lamp            
        TabMNA                  matlab.ui.container.Tab            % Modelo...
        PanMNA                  matlab.ui.container.Panel          % Modelo...
        Label13                 matlab.ui.control.Label            % Elétrico
        DropEletrico            matlab.ui.control.DropDown         % LIF, Z...
        Label14                 matlab.ui.control.Label            % Acústico
        DropAcustico            matlab.ui.control.DropDown         % Zilany
        LabelNumericEditField14 matlab.ui.control.Label            % Num. n...
        NumNNeuro               matlab.ui.control.NumericEditField % [-Inf ...
        Label19                 matlab.ui.control.Label            % Espalh...
        Drop_tipo_esp_corr      matlab.ui.control.DropDown         % Gauss,...
        But_simulaMNA           matlab.ui.control.Button           % Simula...
        TabVoc                  matlab.ui.container.Tab            % Vocoders
        Panel6                  matlab.ui.container.Panel          % Compar...
        LabelKnob               matlab.ui.control.Label            % Volume
        Knob                    matlab.ui.control.Knob             % [0 100]
        ButtonGroup             matlab.ui.container.ButtonGroup    % Sinais...
        ToggleSinal             matlab.ui.control.ToggleButton     % Sinal ...
        ToggleVocTrad           matlab.ui.control.ToggleButton     % Vocode...
        ToggleNvoc              matlab.ui.control.ToggleButton     % Neural...
        But_playVocoders        matlab.ui.control.Button           % Play
        Panel7                  matlab.ui.container.Panel          % Propri...
        But_Nvocoder            matlab.ui.control.Button           % Simula...
        StateButton             matlab.ui.control.StateButton      % State ...
        TabPlots                matlab.ui.container.Tab            % Plots
        UIAxes2                 matlab.ui.control.UIAxes           % Title
        But_plotEletrodograma   matlab.ui.control.Button           % Eletro...
        But_plotNeurograma      matlab.ui.control.Button           % Neurog...
        But_plotEspectrograma   matlab.ui.control.Button           % Espect...
        Tab3                    matlab.ui.container.Tab            % Help
        AxesFiltros             matlab.ui.control.UIAxes           % Title
        LabelTextArea           matlab.ui.control.Label            % Resumo...
        TextArea                matlab.ui.control.TextArea        
        But_plotEletrodograma1  matlab.ui.control.Button           % Eletro...
        props_ModElet           matlab.ui.container.Panel          % Propri...
        Label74                 matlab.ui.control.Label            % Disc. ...
        Num_dn_freq_amost       matlab.ui.control.NumericEditField % [-Inf ...
        Label75                 matlab.ui.control.Label            % Disc. ...
        Num_dtn_A               matlab.ui.control.NumericEditField % [-Inf ...
        Label76                 matlab.ui.control.Label            % Resist...
        Num_R_mem_min           matlab.ui.control.NumericEditField % [-Inf ...
        Label77                 matlab.ui.control.Label            % Capaci...
        Num_C_mem_min           matlab.ui.control.NumericEditField % [-Inf ...
        Label78                 matlab.ui.control.Label            % Per. r...
        Num_dt_refrat_min       matlab.ui.control.NumericEditField % [-Inf ...
        Label79                 matlab.ui.control.Label            % Pot. l...
        Num_V_thr_mem_min       matlab.ui.control.NumericEditField % [-Inf ...
        Label80                 matlab.ui.control.Label            % Pot. m...
        Num_V_rest_mem_min      matlab.ui.control.NumericEditField % [-Inf ...
        Label81                 matlab.ui.control.Label            % Pot. d...
        Num_V_ruido_mem_min     matlab.ui.control.NumericEditField % [-Inf ...
        Num_R_mem_max           matlab.ui.control.NumericEditField % [-Inf ...
        Num_C_mem_max           matlab.ui.control.NumericEditField % [-Inf ...
        Num_dt_refrat_mem_max   matlab.ui.control.NumericEditField % [-Inf ...
        Num_V_thr_mem_max       matlab.ui.control.NumericEditField % [-Inf ...
        Num_V_rest_mem_max      matlab.ui.control.NumericEditField % [-Inf ...
        Num_V_ruido_mem_max     matlab.ui.control.NumericEditField % [-Inf ...
        TextArea2               matlab.ui.control.TextArea         % As pro...
        Label82                 matlab.ui.control.Label            % Min
        Label83                 matlab.ui.control.Label            % Max
        TabGroup2               matlab.ui.container.TabGroup       % Posiçã...
        Tab4                    matlab.ui.container.Tab            % Posiçã...
        Label84                 matlab.ui.control.Label            % 1
        NumPosel1               matlab.ui.control.NumericEditField % [-Inf ...
        Label85                 matlab.ui.control.Label            % 2
        NumPosel2               matlab.ui.control.NumericEditField % [-Inf ...
        Label86                 matlab.ui.control.Label            % 3
        NumPosel3               matlab.ui.control.NumericEditField % [-Inf ...
        Label87                 matlab.ui.control.Label            % 4
        NumPosel4               matlab.ui.control.NumericEditField % [-Inf ...
        Label88                 matlab.ui.control.Label            % 5
        NumPosel5               matlab.ui.control.NumericEditField % [-Inf ...
        Label89                 matlab.ui.control.Label            % 6
        NumPosel6               matlab.ui.control.NumericEditField % [-Inf ...
        Label90                 matlab.ui.control.Label            % 7
        NumPosel7               matlab.ui.control.NumericEditField % [-Inf ...
        Label91                 matlab.ui.control.Label            % 9
        NumPosel8               matlab.ui.control.NumericEditField % [-Inf ...
        Label92                 matlab.ui.control.Label            % 8
        NumPosel9               matlab.ui.control.NumericEditField % [-Inf ...
        Label93                 matlab.ui.control.Label            % 10
        NumPosel10              matlab.ui.control.NumericEditField % [-Inf ...
        Label94                 matlab.ui.control.Label            % 11
        NumPosel11              matlab.ui.control.NumericEditField % [-Inf ...
        Label95                 matlab.ui.control.Label            % 12
        Label96                 matlab.ui.control.Label            % 13
        Label97                 matlab.ui.control.Label            % 14
        Label98                 matlab.ui.control.Label            % 15
        Label99                 matlab.ui.control.Label            % 16
        Label100                matlab.ui.control.Label            % 17
        Label101                matlab.ui.control.Label            % 18
        Label102                matlab.ui.control.Label            % 20
        Label103                matlab.ui.control.Label            % 19
        Label104                matlab.ui.control.Label            % 21
        Label105                matlab.ui.control.Label            % 22
        NumPosel12              matlab.ui.control.NumericEditField % [-Inf ...
        NumPosel13              matlab.ui.control.NumericEditField % [-Inf ...
        NumPosel14              matlab.ui.control.NumericEditField % [-Inf ...
        NumPosel15              matlab.ui.control.NumericEditField % [-Inf ...
        NumPosel16              matlab.ui.control.NumericEditField % [-Inf ...
        NumPosel17              matlab.ui.control.NumericEditField % [-Inf ...
        NumPosel18              matlab.ui.control.NumericEditField % [-Inf ...
        NumPosel20              matlab.ui.control.NumericEditField % [-Inf ...
        NumPosel19              matlab.ui.control.NumericEditField % [-Inf ...
        NumPosel21              matlab.ui.control.NumericEditField % [-Inf ...
        NumPosel22              matlab.ui.control.NumericEditField % [-Inf ...
        Tab5                    matlab.ui.container.Tab            % Impedâ...
        NumZel12                matlab.ui.control.NumericEditField % [-Inf ...
        NumZel13                matlab.ui.control.NumericEditField % [-Inf ...
        NumZel14                matlab.ui.control.NumericEditField % [-Inf ...
        NumZel15                matlab.ui.control.NumericEditField % [-Inf ...
        NumZel16                matlab.ui.control.NumericEditField % [-Inf ...
        NumZel17                matlab.ui.control.NumericEditField % [-Inf ...
        NumZel18                matlab.ui.control.NumericEditField % [-Inf ...
        NumZel19                matlab.ui.control.NumericEditField % [-Inf ...
        NumZel20                matlab.ui.control.NumericEditField % [-Inf ...
        NumZel21                matlab.ui.control.NumericEditField % [-Inf ...
        NumZel22                matlab.ui.control.NumericEditField % [-Inf ...
        NumZel1                 matlab.ui.control.NumericEditField % [-Inf ...
        NumZel2                 matlab.ui.control.NumericEditField % [-Inf ...
        NumZel3                 matlab.ui.control.NumericEditField % [-Inf ...
        NumZel4                 matlab.ui.control.NumericEditField % [-Inf ...
        NumZel5                 matlab.ui.control.NumericEditField % [-Inf ...
        NumZel6                 matlab.ui.control.NumericEditField % [-Inf ...
        NumZel7                 matlab.ui.control.NumericEditField % [-Inf ...
        NumZel8                 matlab.ui.control.NumericEditField % [-Inf ...
        NumZel9                 matlab.ui.control.NumericEditField % [-Inf ...
        NumZel10                matlab.ui.control.NumericEditField % [-Inf ...
        NumZel11                matlab.ui.control.NumericEditField % [-Inf ...
        Label106                matlab.ui.control.Label            % 1
        Label107                matlab.ui.control.Label            % 2
        Label108                matlab.ui.control.Label            % 3
        Label109                matlab.ui.control.Label            % 4
        Label110                matlab.ui.control.Label            % 5
        Label111                matlab.ui.control.Label            % 6
        Label112                matlab.ui.control.Label            % 7
        Label113                matlab.ui.control.Label            % 9
        Label114                matlab.ui.control.Label            % 8
        Label115                matlab.ui.control.Label            % 10
        Label116                matlab.ui.control.Label            % 11
        Label117                matlab.ui.control.Label            % 12
        Label118                matlab.ui.control.Label            % 13
        Label119                matlab.ui.control.Label            % 14
        Label120                matlab.ui.control.Label            % 15
        Label121                matlab.ui.control.Label            % 16
        Label122                matlab.ui.control.Label            % 17
        Label123                matlab.ui.control.Label            % 18
        Label124                matlab.ui.control.Label            % 20
        Label125                matlab.ui.control.Label            % 19
        Label126                matlab.ui.control.Label            % 21
        Label127                matlab.ui.control.Label            % 22
        Label128                matlab.ui.control.Label            % Dist. ...
        Num_dx_eletrodo         matlab.ui.control.NumericEditField % [-Inf ...
        Label129                matlab.ui.control.Label            % Pos. i...
        Num_pos_inicial         matlab.ui.control.NumericEditField % [-Inf ...
        Lamp_MNA                matlab.ui.control.Lamp            
        But_VocoderTrad         matlab.ui.control.Button           % Simula...
        LampSinal               matlab.ui.control.Lamp            
        LampVocoder             matlab.ui.control.Lamp            
        LampNVocoder            matlab.ui.control.Lamp            
        Label130                matlab.ui.control.Label            % Carrier
        DropDown                matlab.ui.control.DropDown         % Ruido ...
        Label131                matlab.ui.control.Label            % Num. h...
        Num_numhar_HC           matlab.ui.control.NumericEditField % [-Inf ...
        Label132                matlab.ui.control.Label            % Discre...
        Num_df_HC               matlab.ui.control.NumericEditField % [-Inf ...
        Label133                matlab.ui.control.Label            % Freq. ...
        Num_F0_HC               matlab.ui.control.NumericEditField % [-Inf ...
    end

    
    properties (Access = public)
        T_Level % Description
        C_Level % Description
    end
    
    properties (Access = private)
        sim % Description
        Property % Description
    end
    
    methods (Access = private)
        
        function results = circle(app)
            r=1;
            x=0;
            y=0;
            
            rA = app.NumDistAlvo.Value/100;
            rR = app.NumDistRuido.Value/100;
            thetaA = pi*app.NumAngAlvo.Value/180;
            thetaR = pi*app.NumAngRuido.Value/180;
            
            
            th = 0:pi/50:2*pi;
            xunit = r * cos(th) + x;
            yunit = r * sin(th) + y;
            
            xAlvo = rA*cos(-thetaA);
            yAlvo = rA*sin(-thetaA);
            xRuido = rR*cos(-thetaR);
            yRuido = rR*sin(-thetaR);
            
            
            plot(app.axisSig,xAlvo,yAlvo,'*')
            hold(app.axisSig, 'on')
            plot(app.axisSig,xRuido,yRuido,'*')
            
            plot(app.axisSig,0.8*xunit, 0.8*yunit,'k:');
            plot(app.axisSig,3*xunit, 3*yunit,'k--');
            xlim(app.axisSig, [-3.1, 3.1])
            ylim(app.axisSig, [-3.1, 3.1])
            %text(app.axisSig,-0.09,-0,'\subset','fontsize',15)
            %text(app.axisSig, 0.03,-0,'\supset','fontsize',15)
            plot(app.axisSig,[0.1,0.25,0.1],[-0.1,0,0.1],'k')
            plot(app.axisSig,xunit/7, yunit/7,'k');
            % text(app.axisSig,-0.5,0,'O','fontsize',24)
            %legend(app.axisSig,'Ouvinte','Posição alvo', 'Posição ruído',...
            %  'location','EastOutside')
            hold(app.axisSig, 'off')
        end
        
        function results = plotBancoFiltros(app)
            switch app.sim.tipo_filtro
                case 0 % ERB
                    np = 2048;
                    y = cochlearFilterBank(app.sim.freq_amost, app.sim.num_canais,app.sim.central_freq(1), [1 zeros(1,(np-1))]);
                    resp = 20*log10(abs(fft(y')));
                    freqScale = (0:(np-1))/np*app.sim.freq_amost;
                    semilogx(app.AxesFiltros,freqScale(1:(np/2-1)),resp(1:(np/2-1),:),'LineWidth',1);
                    axis(app.AxesFiltros,[1e2 0.8e4 -80 0])
                    xlabel(app.AxesFiltros,'Frequencia (Hz)','FontSize',10);
                    ylabel(app.AxesFiltros,'Resposta (dB)','FontSize',10);
                    
                case 1 % Nucleus
                    np = 2048;
                    y = CIFilterBank(app.sim.freq_amost, app.sim.num_canais,app.sim.central_freq(1), [1 zeros(1,(np-1))]);
                    resp = 20*log10(abs(fft(y')));
                    freqScale = (0:(np-1))/np*app.sim.freq_amost;
                    semilogx(app.AxesFiltros,freqScale(1:(np/2-1)),resp(1:(np/2-1),:),'LineWidth',1);
                    axis(app.AxesFiltros,[1e2 0.8e4 -80 0])
                    xlabel(app.AxesFiltros,'Frequencia (Hz)','FontSize',10);
                    ylabel(app.AxesFiltros,'Resposta (dB)','FontSize',10);
                otherwise
                    error('Somente as seguintes opcoes: 0 - ERB / 1 - Nucleus');
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cd
            addpath(genpath('./funcoes'))
            addpath(genpath('./sinais_entrada'))
            addpath(genpath('./dados_pacientes'))
            Pacientes = dir([pwd,'/dados_pacientes/*.dat']);
            app.List_pacientes.Items = cellstr({Pacientes.name});
            
            
            app.sim = CsimIC(app.List_pacientes.Value,'asa.wav');
            
            Sinais = dir([pwd,'/sinais_entrada/*.wav']);
            app.DropSignal.Items = cellstr({Sinais.name});
            app.sim.arqX = app.DropSignal.Value;
            
            Ruidos = dir([pwd,'/sinais_entrada/ruidos/*.wav']);
            app.DropRuido.Items = cellstr({Ruidos.name; 'sem_ruido'});
            app.sim.arqR = app.DropRuido.Value;
            
            circle(app)
            ButImportPacButtonPushed(app)
            plotBancoFiltros(app)    
            
            
            for iCh = 1:app.sim.num_canais
                app.(strcat('NumPosel',num2str(iCh))).Value = app.sim.pos_eletrodo(1,iCh); %num_canais - iCh+1);
                app.(strcat('NumZel',num2str(iCh))).Value = app.sim.Z_eletrodo(iCh,1);                
            end
        end

        % ButImportPac button pushed function
        function ButImportPacButtonPushed(app)
            
            % Carrega dados paciente
            fileName = app.List_pacientes.Value;
            % Atualiza campos da GUI
            app.Num_num_canais.Value = max(dlmread(fileName,'\t',[15 0 36 0]));
            app.Num_maxima.Value = dlmread(fileName,'\t',[3 1 3 1]);
            app.Num_interphase_gap.Value = mean(dlmread(fileName,'\t',[4 1 4 1]))*1e-6;
            app.Num_largura_pulso1.Value = mean(dlmread(fileName,'\t',[15 7 36 7]))*1e-6;
            % app.DropLGF = (dlmread(fileName,'\t',[7 1 7 1]));
            
            % obj.largura_pulso2 = mean(dlmread(fileName,'\t',[15 7 36 7]))*1e-6;
            T_SPL = dlmread(fileName,'\t',[8 1 8 1]);
            C_SPL = dlmread(fileName,'\t',[9 1 9 1]);
            app.T_Level = dlmread(fileName,'\t',[15 3 36 3]);
            app.C_Level = dlmread(fileName,'\t',[15 4 36 4]);
            
            % Atribui vetores de T e C level aos campos de mapeamento da GUI
            for iCh = 1:app.Num_num_canais.Value;
                app.(strcat('Num_M',num2str(iCh),num2str(1))).Value = app.T_Level(iCh,1);
                app.(strcat('Num_M',num2str(iCh),num2str(2))).Value = app.C_Level(iCh,1);
            end


            %             obj.inf_freq = dlmread(fileName,'\t',[15 9 36 9]);
            %             obj.sup_freq = dlmread(fileName,'\t',[15 10 36 10]);
            %
            %
            
        end

        % ButSimProcess button pushed function
        function ButSimProcessButtonPushed(app)
            app.LampProcess.Color = 'y';
            
            
            % Atualiza parametros paciente
            app.sim.num_canais = app.Num_num_canais.Value;
            app.sim.maxima = app.Num_maxima.Value;
            app.sim.taxa_est = app.Num_taxa_est.Value;
            app.sim.largura_pulso1 = app.Num_largura_pulso1.Value;
            app.sim.largura_pulso2 = app.Num_largura_pulso1.Value;
            app.sim.LGF = app.DropLGF;
            app.sim.interphase_gap = app.Num_interphase_gap.Value;
            for iCh = 1:app.Num_num_canais.Value;
                app.T_Level(iCh,1) = app.(strcat('Num_M',num2str(iCh),num2str(1))).Value;
                app.C_Level(iCh,1) = app.(strcat('Num_M',num2str(iCh),num2str(2))).Value;
            end
            
            % Atualiza parametros processador
            
            
            app.But_plotEletrodograma1.Enable = 'on';
            
            % processamento do implante
            app.sim.cis_ace();
            app.LampProcess.Color = 'g';
        end

        % NumDistAlvo value changed function
        function NumDistAlvoValueChanged(app)
            value = app.NumDistAlvo.Value;
            circle(app)
            app.sim.dist_alvo = value;
                                    app.LampProcess.Color = 'r';
                        app.But_plotEletrodograma1.Enable = 'off';
        end

        % NumDistRuido value changed function
        function NumDistRuidoValueChanged(app)
            value = app.NumDistRuido.Value;
            circle(app)
            
            app.sim.dist_ruido = value;
                                    app.LampProcess.Color = 'r';
                        app.But_plotEletrodograma1.Enable = 'off';
        end

        % NumAngAlvo value changed function
        function NumAngAlvoValueChanged(app)
            value = app.NumAngAlvo.Value;
            circle(app)
            
            app.sim.azmt_alvo = value;
                                    app.LampProcess.Color = 'r';
                        app.But_plotEletrodograma1.Enable = 'off';
        end

        % NumAngRuido value changed function
        function NumAngRuidoValueChanged(app)
            value = app.NumAngRuido.Value;
            circle(app)
            
            app.sim.azmt_ruido = value;                        app.LampProcess.Color = 'r';
                        app.But_plotEletrodograma1.Enable = 'off';
        end

        % ButGeraSinal button pushed function
        function ButGeraSinalButtonPushed(app)
            app.ButPlaySinal.Enable='on';
            
            app.sim.mesclar
            
            app.LampSignal.Color = 'g';
            
        end

        % ToggleSwitch value changed function
        function ToggleSwitchValueChanged(app)
            value = app.ToggleSwitch.Value;
            if strcmp('Esquerdo',value)
                app.sim.lado = 'esquerda';
            else
                app.sim.lado = 'direita';
            end
                        app.LampProcess.Color = 'r';
                        app.But_plotEletrodograma1.Enable = 'off';
        end

        % DropRuido value changed function
        function DropRuidoValueChanged(app, event)
            value = app.DropRuido.Value;
            app.sim.arqR = value;
            
            if strcmp(value,'sem_ruido')
                app.NumSNR.Value = 9999;
                app.NumSNR.Enable = 'off';
            else
                app.NumSNR.Value = 10;
                app.NumSNR.Enable = 'on';
            end
            
                        app.LampProcess.Color = 'r';
                        app.But_plotEletrodograma1.Enable = 'off';
        end

        % DropSignal value changed function
        function DropSignalValueChanged(app, event)
            value = app.DropSignal.Value;
            app.sim.arqX = value;
                        app.LampProcess.Color = 'r';
                        app.But_plotEletrodograma1.Enable = 'off';
        end

        % ButPlaySinal button pushed function
        function ButPlaySinalButtonPushed(app)
            app.LampSignal.Visible = 'on';
            app.sim.reproduz_audio('alvo');
            app.LampSignal.Visible = 'off';
            
        end

        % ButPlayRuido button pushed function
        function ButPlayRuidoButtonPushed(app)
            app.LampSignal.Visible = 'on';
            app.sim.reproduz_audio('ruido');
            app.LampSignal.Visible = 'off';
            
        end

        % ButPlayMescla button pushed function
        function ButPlayMesclaButtonPushed(app)
            app.LampSignal.Visible = 'on';
            app.sim.reproduz_audio('mescla');
            app.LampSignal.Visible = 'off';
            
        end

        % NumSNR value changed function
        function NumSNRValueChanged(app)
            value = app.NumSNR.Value;
            app.sim.SNRdB = value;
            app.LampProcess.Color = 'r';
                        app.But_plotEletrodograma1.Enable = 'off';
                        
        end

        % StateMapeamento value changed function
        function StateMapeamentoValueChanged(app)
            value = app.StateMapeamento.Value;
            if value
                app.Tab.Enable='on';
            else
                app.Tab.Enable='off';
            end
            
        end

        % Spin_dist_alv value changed function
        function Spin_dist_alvValueChanged(app)
            value = app.Spin_dist_alv.Value;
            
            circle(app)
            app.sim.dist_alvo = value;
                        app.LampProcess.Color = 'r';
                        app.But_plotEletrodograma1.Enable = 'off';
        end

        % Spin_azmt_alvo value changed function
        function Spin_azmt_alvoValueChanged(app)
            value = app.Spin_azmt_alvo.Value;
            circle(app)
            app.sim.azmt_alvo = value;
            app.LampProcess.Color = 'r';
                        app.But_plotEletrodograma1.Enable = 'off';
        end

        % Spin_dist_ruido value changed function
        function Spin_dist_ruidoValueChanged(app)
            value = app.Spin_dist_ruido.Value;
            circle(app)
            app.sim.dist_ruido = value;
            app.LampProcess.Color = 'r';
        end

        % Spin_azmt_ruido value changed function
        function Spin_azmt_ruidoValueChanged(app)
            value = app.Spin_azmt_ruido.Value;
            circle(app)
            app.sim.azmt_ruido = value;
            app.LampProcess.Color = 'r';
        end

        % DropBancFilt value changed function
        function DropBancFiltValueChanged(app, event)
            value = app.DropBancFilt.Value;
            switch value
                case 'ERB'
                    app.sim.tipo_filtro = 0;
                case 'Nucleus'
                    app.sim.tipo_filtro = 1;
            end
            
            plotBancoFiltros(app)
        end

        % DropExtrEnvolt value changed function
        function DropExtrEnvoltValueChanged(app, event)
            value = app.DropExtrEnvolt.Value;
                        switch value
                case 'Hilbert'
                    app.sim.tipo_env = 0;
                case 'Retificado'
                    app.sim.tipo_env = 1;
            end
            app.LampProcess.Color = 'r';
                        app.But_plotEletrodograma1.Enable = 'off';
        end

        % NumCutFPB value changed function
        function NumCutFPBValueChanged(app)
            value = app.NumCutFPB.Value;
            app.sim.fcorte_fpb = value;
           app.LampProcess.Color = 'r';
                       app.But_plotEletrodograma1.Enable = 'off';
        end

        % NumOrdFPB value changed function
        function NumOrdFPBValueChanged(app)
            value = app.NumOrdFPB.Value;
            app.sim.ordem_fpb = value;
            app.LampProcess.Color = 'r';
            app.But_plotEletrodograma1.Enable = 'off';
        end

        % But_plotEletrodograma1 button pushed function
        function But_plotEletrodograma1ButtonPushed(app)
            app.sim.plotEletrodograma()
        end

        % NumNNeuro value changed function
        function NumNNeuroValueChanged(app)
            value = app.NumNNeuro.Value;
            app.sim.N_neurons = value;
        end

        % Drop_tipo_esp_corr value changed function
        function Drop_tipo_esp_corrValueChanged(app, event)
            value = app.Drop_tipo_esp_corr.Value;
            switch value
                case 'Gauss'
                    app.sim.tipo_esp_corr = 1;
                case 'Exponencial' 
                    app.sim.tipo_esp_corr = 0;
            end
        end

        % Num_dn_freq_amost value changed function
        function Num_dn_freq_amostValueChanged(app)
            value = app.Num_dn_freq_amost.Value;
            app.sim.dn_freq_amost = value;
        end

        % Num_dtn_A value changed function
        function Num_dtn_AValueChanged(app)
            value = app.Num_dtn_A.Value;
            app.sim.dtn_A = value;
        end

        % Num_dx_eletrodo value changed function
        function Num_dx_eletrodoValueChanged(app)
            value = app.Num_dx_eletrodo.Value;
            app.sim.dx_eletrodo = value;
        end

        % Num_pos_inicial value changed function
        function Num_pos_inicialValueChanged(app)
            value = app.Num_pos_inicial.Value;
            app.sim.pos_inicial = value;
        end

        % Num_R_mem_min value changed function
        function Num_R_mem_minValueChanged(app)
            value_min = app.Num_R_mem_min.Value;
            value_max = app.Num_R_mem_max.Value;
            
            if value_min >= value_max
                app.Num_R_mem_min.Value = value_max - 1000;
            else
                app.sim.R_mem = [value_min value_max];
            end
        end

        % Num_R_mem_max value changed function
        function Num_R_mem_maxValueChanged(app)
            value_min = app.Num_R_mem_min.Value;
            value_max = app.Num_R_mem_max.Value;
            
            if value_min >= value_max
                app.Num_R_mem_max.Value = value_min + 1000;
            else
                app.sim.R_mem = [value_min value_max];
            end
            
        end

        % Num_C_mem_min value changed function
        function Num_C_mem_minValueChanged(app)
            value_min = app.Num_C_mem_min.Value;
            value_max = app.Num_C_mem_max.Value;
            
            if value_min >= value_max
                app.Num_C_mem_min.Value = value_max - 1;
            else
                app.sim.C_mem = [value_min value_max];
            end
            
        end

        % Num_C_mem_max value changed function
        function Num_C_mem_maxValueChanged(app)
            value_min = app.Num_C_mem_min.Value;
            value_max = app.Num_C_mem_max.Value;
            
            if value_min >= value_max
                app.Num_C_mem_max.Value = value_min + 1;
            else
                app.sim.C_mem = [value_min value_max];
            end
            
            
        end

        % Num_dt_refrat_min value changed function
        function Num_dt_refrat_minValueChanged(app)
            value_min = app.Num_dt_refrat_min.Value;
            value_max = app.Num_dt_refrat_max.Value;
            
            if value_min >= value_max
                app.Num_dt_refrat_min.Value = value_max - 1;
            else
                app.sim.dt_refrat = [value_min value_max];
            end
            
        end

        % Num_dt_refrat_mem_max value changed function
        function Num_dt_refrat_mem_maxValueChanged(app)
            value_min = app.Num_dt_refrat_min.Value;
            value_max = app.Num_dt_refrat_max.Value;
            
            if value_min >= value_max
                app.Num_dt_refrat_max.Value = value_min + 1;
            else
                app.sim.dt_refrat = [value_min value_max];
            end
        end

        % Num_V_thr_mem_min value changed function
        function Num_V_thr_mem_minValueChanged(app)
            value_min = app.Num_V_thr_mem_min.Value;
            value_max = app.Num_V_thr_mem_max.Value;
            
            if value_min >= value_max
                app.Num_V_thr_mem_max.Value = value_max - 1;
            else
                app.sim.V_thr_mem = [value_min value_max];
            end
            
        end

        % Num_V_thr_mem_max value changed function
        function Num_V_thr_mem_maxValueChanged(app)
            value_min = app.Num_V_thr_mem_min.Value;
            value_max = app.Num_V_thr_mem_max.Value;
            
            if value_min >= value_max
                app.Num_V_thr_mem_max.Value = value_min + 1;
            else
                app.sim.V_thr_mem = [value_min value_max];
            end
        end

        % Num_V_rest_mem_min value changed function
        function Num_V_rest_mem_minValueChanged(app)
            value_min = app.Num_V_rest_mem_min.Value;
            value_max = app.Num_V_rest_mem_max.Value;
            
            if value_min >= value_max
                app.Num_V_rest_mem_max.Value = value_max - 1;
            else
                app.sim.V_rest_mem = [value_min value_max];
            end
            
        end

        % Num_V_rest_mem_max value changed function
        function Num_V_rest_mem_maxValueChanged(app)
            value_min = app.Num_V_rest_mem_min.Value;
            value_max = app.Num_V_rest_mem_max.Value;
            
            if value_min >= value_max
                app.Num_V_rest_mem_max.Value = value_min + 1;
            else
                app.sim.V_rest_mem = [value_min value_max];
            end
        end

        % Num_V_ruido_mem_min value changed function
        function Num_V_ruido_mem_minValueChanged(app)
            value_min = app.Num_V_ruido_mem_min.Value;
            value_max = app.Num_V_ruido_mem_max.Value;
            
            if value_min >= value_max
                app.Num_V_ruido_mem_max.Value = value_max - 1;
            else
                app.sim.V_ruido_mem = [value_min value_max];
            end
            
        end

        % Num_V_ruido_mem_max value changed function
        function Num_V_ruido_mem_maxValueChanged(app)
            value_min = app.Num_V_ruido_mem_min.Value;
            value_max = app.Num_V_ruido_mem_max.Value;
            
            if value_min >= value_max
                app.Num_V_ruido_mem_max.Value = value_min + 1;
            else
                app.sim.V_ruido_mem = [value_min value_max];
            end
            
        end

        % But_simulaMNA button pushed function
        function But_simulaMNAButtonPushed(app)
            
            app.Lamp_MNA.Color = 'y';
            for iCh = 1:app.sim.num_canais
                app.sim.pos_eletrodo(1,iCh) = app.(strcat('NumPosel',num2str(iCh))).Value;
                app.sim.Z_eletrodo(iCh,1) = app.(strcat('NumZel',num2str(iCh))).Value;                
            end            
            
            app.sim.LIF()
            app.sim.Modelo_Zilany
            
            app.Lamp_MNA.Color = 'g';
        end

        % But_Nvocoder button pushed function
        function But_NvocoderButtonPushed(app)
            app.LampNVocoder.Color = 'g';
        end

        % But_VocoderTrad button pushed function
        function But_VocoderTradButtonPushed(app)
            app.LampVocoder.Color = 'g';
        end

        % But_playVocoders button pushed function
        function But_playVocodersButtonPushed(app)
            if app.ToggleSinal.Value ==1
                ButPlaySinalButtonPushed(app);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.TabSignal = uitab(app.TabGroup);
            app.TabSignal.Units = 'pixels';
            app.TabSignal.Title = 'Sinal de entrada';
            app.PanSinEntr = uipanel(app.TabSignal);
            app.PanSinEntr.Title = 'Sinal de entrada';
            app.PanSinEntr.Units = 'pixels';
            app.NumDistAlvo = uieditfield(app.PanSinEntr, 'numeric');
            app.NumDistAlvo.ValueChangedFcn = createCallbackFcn(app, @NumDistAlvoValueChanged);
            app.NumDistAlvo.Limits = [80 300];
            app.NumDistAlvo.Value = 80;
            app.Label4 = uilabel(app.PanSinEntr);
            app.Label4.Text = 'Ângulo alvo [ º ]';
            app.NumAngAlvo = uieditfield(app.PanSinEntr, 'numeric');
            app.NumAngAlvo.ValueChangedFcn = createCallbackFcn(app, @NumAngAlvoValueChanged);
            app.NumAngAlvo.Limits = [-180 180];
            app.NumAngAlvo.Value = 30;
            app.Label5 = uilabel(app.PanSinEntr);
            app.Label5.Text = 'Dist. ruído [ cm ]';
            app.NumDistRuido = uieditfield(app.PanSinEntr, 'numeric');
            app.NumDistRuido.ValueChangedFcn = createCallbackFcn(app, @NumDistRuidoValueChanged);
            app.NumDistRuido.Limits = [80 300];
            app.NumDistRuido.Value = 300;
            app.Label6 = uilabel(app.PanSinEntr);
            app.Label6.Text = 'Ângulo ruído [ º ]';
            app.NumAngRuido = uieditfield(app.PanSinEntr, 'numeric');
            app.NumAngRuido.ValueChangedFcn = createCallbackFcn(app, @NumAngRuidoValueChanged);
            app.NumAngRuido.Limits = [-180 180];
            app.NumAngRuido.Value = 10;
            app.Label7 = uilabel(app.PanSinEntr);
            app.Label7.Text = 'SNR [ dB ]';
            app.NumSNR = uieditfield(app.PanSinEntr, 'numeric');
            app.NumSNR.ValueChangedFcn = createCallbackFcn(app, @NumSNRValueChanged);
            app.NumSNR.Value = 10;
            app.Label8 = uilabel(app.PanSinEntr);
            app.Label8.Text = 'Ruído';
            app.DropRuido = uidropdown(app.PanSinEntr);
            app.DropRuido.Items = {'RB', 'White', 'Tráfego'};
            app.DropRuido.ValueChangedFcn = createCallbackFcn(app, @DropRuidoValueChanged, true);
            app.DropRuido.Value = 'RB';
            app.Label42 = uilabel(app.PanSinEntr);
            app.Label42.Text = 'Sinal';
            app.DropSignal = uidropdown(app.PanSinEntr);
            app.DropSignal.Items = {'RB', 'White', 'Tráfego'};
            app.DropSignal.ValueChangedFcn = createCallbackFcn(app, @DropSignalValueChanged, true);
            app.DropSignal.Value = 'RB';
            app.Label3 = uilabel(app.PanSinEntr);
            app.Label3.Text = 'Dist. alvo [ cm ]';
            app.axisSig = uiaxes(app.TabSignal);
            app.Panel9 = uipanel(app.TabSignal);
            app.Panel9.Title = 'Panel';
            app.Panel9.Units = 'pixels';
            app.ButPlaySinal = uibutton(app.Panel9, 'push');
            app.ButPlaySinal.ButtonPushedFcn = createCallbackFcn(app, @ButPlaySinalButtonPushed);
            app.ButPlaySinal.Icon = 'playIcon.jpg';
            app.ButPlaySinal.Text = 'Ouvir sinal';
            app.LabelLamp2 = uilabel(app.Panel9);
            app.LabelLamp2.Enable = 'off';
            app.LabelLamp2.Text = 'Reproduzindo';
            app.LampSignal = uilamp(app.Panel9);
            app.LampSignal.Visible = 'off';
            app.LabelKnob2 = uilabel(app.Panel9);
            app.LabelKnob2.Enable = 'off';
            app.LabelKnob2.Text = 'Volume';
            app.Knob2 = uiknob(app.Panel9, 'continuous');
            app.Knob2.MajorTicks = [0 20 40 60 80 100];
            app.Knob2.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.Knob2.Enable = 'off';
            app.ButPlayRuido = uibutton(app.Panel9, 'push');
            app.ButPlayRuido.ButtonPushedFcn = createCallbackFcn(app, @ButPlayRuidoButtonPushed);
            app.ButPlayRuido.Icon = 'playIcon.jpg';
            app.ButPlayRuido.Text = 'Ouvir ruído';
            app.ButPlayMescla = uibutton(app.Panel9, 'push');
            app.ButPlayMescla.ButtonPushedFcn = createCallbackFcn(app, @ButPlayMesclaButtonPushed);
            app.ButPlayMescla.Icon = 'playIcon.jpg';
            app.ButPlayMescla.Text = 'Ouvir mescla';
            app.ToggleSwitch = uiswitch(app.Panel9, 'toggle');
            app.ToggleSwitch.Items = {'Esquerdo', 'Direito'};
            app.ToggleSwitch.ValueChangedFcn = createCallbackFcn(app, @ToggleSwitchValueChanged);
            app.ToggleSwitch.Value = 'Esquerdo';
            app.Label43 = uilabel(app.TabSignal);
            app.Label43.Text = '*';
            app.Label44 = uilabel(app.TabSignal);
            app.Label44.Text = '*';
            app.Label45 = uilabel(app.TabSignal);
            app.Label45.Text = 'Pos. Alvo';
            app.Label46 = uilabel(app.TabSignal);
            app.Label46.Text = 'Pos. Ruído';
            app.TabPaci = uitab(app.TabGroup);
            app.TabPaci.Units = 'pixels';
            app.TabPaci.Title = 'Paciente';
            app.PanPac = uipanel(app.TabPaci);
            app.PanPac.Title = 'Propriedades';
            app.PanPac.Units = 'pixels';
            app.ButImportPac = uibutton(app.PanPac, 'push');
            app.ButImportPac.ButtonPushedFcn = createCallbackFcn(app, @ButImportPacButtonPushed);
            app.ButImportPac.Text = 'Importar paciente';
            app.LabelDropDown = uilabel(app.PanPac);
            app.LabelDropDown.Text = 'Estratégia';
            app.DropStrat = uidropdown(app.PanPac);
            app.DropStrat.Items = {'ACE', 'CIS'};
            app.DropStrat.Value = 'ACE';
            app.LabelNumericEditField2 = uilabel(app.PanPac);
            app.LabelNumericEditField2.Text = 'Num máximas';
            app.Num_maxima = uieditfield(app.PanPac, 'numeric');
            app.Num_maxima.Value = 10;
            app.LabelNumericEditField3 = uilabel(app.PanPac);
            app.LabelNumericEditField3.Text = 'Taxa de estímulo';
            app.Num_taxa_est = uieditfield(app.PanPac, 'numeric');
            app.Num_taxa_est.Value = 800;
            app.LabelNumericEditField4 = uilabel(app.PanPac);
            app.LabelNumericEditField4.Text = 'Largura pulso [ s ]';
            app.Num_largura_pulso1 = uieditfield(app.PanPac, 'numeric');
            app.Num_largura_pulso1.Value = 2.5e-05;
            app.LabelNumericEditField5 = uilabel(app.PanPac);
            app.LabelNumericEditField5.Text = 'IPG [ s ]';
            app.Num_interphase_gap = uieditfield(app.PanPac, 'numeric');
            app.Num_interphase_gap.Value = 8e-06;
            app.LabelDropDown2 = uilabel(app.PanPac);
            app.LabelDropDown2.Text = 'LGF';
            app.DropLGF = uidropdown(app.PanPac);
            app.DropLGF.Items = {'20', '30', '40', '50'};
            app.DropLGF.Editable = 'on';
            app.DropLGF.Value = '20';
            app.Label = uilabel(app.PanPac);
            app.Label.Text = 'Num. canais';
            app.Num_num_canais = uieditfield(app.PanPac, 'numeric');
            app.Num_num_canais.Limits = [8 22];
            app.Num_num_canais.Value = 22;
            app.Panel8 = uipanel(app.TabPaci);
            app.Panel8.Title = 'Propriedades';
            app.Panel8.Units = 'pixels';
            app.List_pacientes = uilistbox(app.Panel8);
            app.List_pacientes.Value = 'Item 3';
            app.TabMapeamento = uitabgroup(app.TabPaci);
            app.TabMapeamento.Units = 'pixels';
            app.Tab = uitab(app.TabMapeamento);
            app.Tab.Units = 'pixels';
            app.Tab.Title = 'T-Level';
            app.LabelNumericEditField = uilabel(app.Tab);
            app.LabelNumericEditField.Text = '1';
            app.Num_M11 = uieditfield(app.Tab, 'numeric');
            app.Label20 = uilabel(app.Tab);
            app.Label20.Text = '2';
            app.Num_M21 = uieditfield(app.Tab, 'numeric');
            app.Label21 = uilabel(app.Tab);
            app.Label21.Text = '3';
            app.Num_M31 = uieditfield(app.Tab, 'numeric');
            app.Label22 = uilabel(app.Tab);
            app.Label22.Text = '4';
            app.Num_M41 = uieditfield(app.Tab, 'numeric');
            app.Label23 = uilabel(app.Tab);
            app.Label23.Text = '5';
            app.Num_M51 = uieditfield(app.Tab, 'numeric');
            app.Label24 = uilabel(app.Tab);
            app.Label24.Text = '6';
            app.Num_M61 = uieditfield(app.Tab, 'numeric');
            app.Label25 = uilabel(app.Tab);
            app.Label25.Text = '7';
            app.Num_M71 = uieditfield(app.Tab, 'numeric');
            app.Label26 = uilabel(app.Tab);
            app.Label26.Text = '9';
            app.Num_M91 = uieditfield(app.Tab, 'numeric');
            app.Label27 = uilabel(app.Tab);
            app.Label27.Text = '8';
            app.Num_M81 = uieditfield(app.Tab, 'numeric');
            app.Label28 = uilabel(app.Tab);
            app.Label28.Text = '10';
            app.Num_M101 = uieditfield(app.Tab, 'numeric');
            app.Label29 = uilabel(app.Tab);
            app.Label29.Text = '11';
            app.Num_M111 = uieditfield(app.Tab, 'numeric');
            app.Label30 = uilabel(app.Tab);
            app.Label30.Text = '12';
            app.Label31 = uilabel(app.Tab);
            app.Label31.Text = '13';
            app.Label32 = uilabel(app.Tab);
            app.Label32.Text = '14';
            app.Label33 = uilabel(app.Tab);
            app.Label33.Text = '15';
            app.Label34 = uilabel(app.Tab);
            app.Label34.Text = '16';
            app.Label35 = uilabel(app.Tab);
            app.Label35.Text = '17';
            app.Label36 = uilabel(app.Tab);
            app.Label36.Text = '18';
            app.Label37 = uilabel(app.Tab);
            app.Label37.Text = '20';
            app.Label38 = uilabel(app.Tab);
            app.Label38.Text = '19';
            app.Label39 = uilabel(app.Tab);
            app.Label39.Text = '21';
            app.Label40 = uilabel(app.Tab);
            app.Label40.Text = '22';
            app.Num_M121 = uieditfield(app.Tab, 'numeric');
            app.Num_M131 = uieditfield(app.Tab, 'numeric');
            app.Num_M141 = uieditfield(app.Tab, 'numeric');
            app.Num_M151 = uieditfield(app.Tab, 'numeric');
            app.Num_M161 = uieditfield(app.Tab, 'numeric');
            app.Num_M171 = uieditfield(app.Tab, 'numeric');
            app.Num_M181 = uieditfield(app.Tab, 'numeric');
            app.Num_M191 = uieditfield(app.Tab, 'numeric');
            app.Num_M201 = uieditfield(app.Tab, 'numeric');
            app.Num_M211 = uieditfield(app.Tab, 'numeric');
            app.Num_M221 = uieditfield(app.Tab, 'numeric');
            app.Tab2 = uitab(app.TabMapeamento);
            app.Tab2.Units = 'pixels';
            app.Tab2.Title = 'C-Level';
            app.Num_M122 = uieditfield(app.Tab2, 'numeric');
            app.Num_M132 = uieditfield(app.Tab2, 'numeric');
            app.Num_M142 = uieditfield(app.Tab2, 'numeric');
            app.Num_M152 = uieditfield(app.Tab2, 'numeric');
            app.Num_M162 = uieditfield(app.Tab2, 'numeric');
            app.Num_M172 = uieditfield(app.Tab2, 'numeric');
            app.Num_M182 = uieditfield(app.Tab2, 'numeric');
            app.Num_M192 = uieditfield(app.Tab2, 'numeric');
            app.Num_M202 = uieditfield(app.Tab2, 'numeric');
            app.Num_M212 = uieditfield(app.Tab2, 'numeric');
            app.Num_M222 = uieditfield(app.Tab2, 'numeric');
            app.Num_M12 = uieditfield(app.Tab2, 'numeric');
            app.Num_M12.ValueDisplayFormat = '%.0f';
            app.Num_M22 = uieditfield(app.Tab2, 'numeric');
            app.Num_M22.ValueDisplayFormat = '%.0f';
            app.Num_M32 = uieditfield(app.Tab2, 'numeric');
            app.Num_M32.ValueDisplayFormat = '%.0f';
            app.Num_M42 = uieditfield(app.Tab2, 'numeric');
            app.Num_M42.ValueDisplayFormat = '%.0f';
            app.Num_M52 = uieditfield(app.Tab2, 'numeric');
            app.Num_M52.ValueDisplayFormat = '%.0f';
            app.Num_M62 = uieditfield(app.Tab2, 'numeric');
            app.Num_M62.ValueDisplayFormat = '%.0f';
            app.Num_M72 = uieditfield(app.Tab2, 'numeric');
            app.Num_M72.ValueDisplayFormat = '%.0f';
            app.Num_M82 = uieditfield(app.Tab2, 'numeric');
            app.Num_M82.ValueDisplayFormat = '%.0f';
            app.Num_M92 = uieditfield(app.Tab2, 'numeric');
            app.Num_M92.ValueDisplayFormat = '%.0f';
            app.Num_M102 = uieditfield(app.Tab2, 'numeric');
            app.Num_M102.ValueDisplayFormat = '%.0f';
            app.Num_M112 = uieditfield(app.Tab2, 'numeric');
            app.Num_M112.ValueDisplayFormat = '%.0f';
            app.Label48 = uilabel(app.Tab2);
            app.Label48.Text = '1';
            app.Label49 = uilabel(app.Tab2);
            app.Label49.Text = '2';
            app.Label50 = uilabel(app.Tab2);
            app.Label50.Text = '3';
            app.Label51 = uilabel(app.Tab2);
            app.Label51.Text = '4';
            app.Label52 = uilabel(app.Tab2);
            app.Label52.Text = '5';
            app.Label53 = uilabel(app.Tab2);
            app.Label53.Text = '6';
            app.Label54 = uilabel(app.Tab2);
            app.Label54.Text = '7';
            app.Label55 = uilabel(app.Tab2);
            app.Label55.Text = '9';
            app.Label56 = uilabel(app.Tab2);
            app.Label56.Text = '8';
            app.Label57 = uilabel(app.Tab2);
            app.Label57.Text = '10';
            app.Label58 = uilabel(app.Tab2);
            app.Label58.Text = '11';
            app.Label59 = uilabel(app.Tab2);
            app.Label59.Text = '12';
            app.Label60 = uilabel(app.Tab2);
            app.Label60.Text = '13';
            app.Label61 = uilabel(app.Tab2);
            app.Label61.Text = '14';
            app.Label62 = uilabel(app.Tab2);
            app.Label62.Text = '15';
            app.Label63 = uilabel(app.Tab2);
            app.Label63.Text = '16';
            app.Label64 = uilabel(app.Tab2);
            app.Label64.Text = '17';
            app.Label65 = uilabel(app.Tab2);
            app.Label65.Text = '18';
            app.Label66 = uilabel(app.Tab2);
            app.Label66.Text = '20';
            app.Label67 = uilabel(app.Tab2);
            app.Label67.Text = '19';
            app.Label68 = uilabel(app.Tab2);
            app.Label68.Text = '21';
            app.Label69 = uilabel(app.Tab2);
            app.Label69.Text = '22';
            app.TabProcess = uitab(app.TabGroup);
            app.TabProcess.Units = 'pixels';
            app.TabProcess.Title = 'Processador';
            app.PanProcess = uipanel(app.TabProcess);
            app.PanProcess.Title = 'Processador';
            app.PanProcess.Units = 'pixels';
            app.Label9 = uilabel(app.PanProcess);
            app.Label9.Text = 'Banco de filtros';
            app.DropBancFilt = uidropdown(app.PanProcess);
            app.DropBancFilt.Items = {'ERB', 'Nucleus'};
            app.DropBancFilt.ValueChangedFcn = createCallbackFcn(app, @DropBancFiltValueChanged, true);
            app.DropBancFilt.Value = 'ERB';
            app.Label10 = uilabel(app.PanProcess);
            app.Label10.Text = 'Extr. envoltória';
            app.DropExtrEnvolt = uidropdown(app.PanProcess);
            app.DropExtrEnvolt.Items = {'Hilbert', 'Retificado'};
            app.DropExtrEnvolt.ValueChangedFcn = createCallbackFcn(app, @DropExtrEnvoltValueChanged, true);
            app.DropExtrEnvolt.Value = 'Hilbert';
            app.Label11 = uilabel(app.PanProcess);
            app.Label11.Text = 'Freq. corte FPB [ Hz ]';
            app.NumCutFPB = uieditfield(app.PanProcess, 'numeric');
            app.NumCutFPB.ValueChangedFcn = createCallbackFcn(app, @NumCutFPBValueChanged);
            app.NumCutFPB.Value = 200;
            app.Label12 = uilabel(app.PanProcess);
            app.Label12.Text = 'Ordem FPB';
            app.NumOrdFPB = uieditfield(app.PanProcess, 'numeric');
            app.NumOrdFPB.ValueChangedFcn = createCallbackFcn(app, @NumOrdFPBValueChanged);
            app.NumOrdFPB.Value = 4;
            app.ButSimProcess = uibutton(app.PanProcess, 'push');
            app.ButSimProcess.ButtonPushedFcn = createCallbackFcn(app, @ButSimProcessButtonPushed);
            app.ButSimProcess.Text = 'Simular processamento';
            app.LabelLamp = uilabel(app.PanProcess);
            app.LabelLamp.Text = 'Status';
            app.LampProcess = uilamp(app.PanProcess);
            app.AxesFiltros = uiaxes(app.TabProcess);
            title(app.AxesFiltros, 'Title');
            xlabel(app.AxesFiltros, 'X');
            ylabel(app.AxesFiltros, 'Y');
            app.LabelTextArea = uilabel(app.TabProcess);
            app.LabelTextArea.Text = 'Resumo da simulação';
            app.TextArea = uitextarea(app.TabProcess);
            app.But_plotEletrodograma1 = uibutton(app.TabProcess, 'push');
            app.But_plotEletrodograma1.ButtonPushedFcn = createCallbackFcn(app, @But_plotEletrodograma1ButtonPushed);
            app.But_plotEletrodograma1.Enable = 'off';
            app.But_plotEletrodograma1.Text = 'Eletrodograma';
            app.TabMNA = uitab(app.TabGroup);
            app.TabMNA.Units = 'pixels';
            app.TabMNA.Title = 'Modelo Nervo auditivo';
            app.PanMNA = uipanel(app.TabMNA);
            app.PanMNA.Title = 'Modelo Nervo Auditivo';
            app.PanMNA.Units = 'pixels';
            app.Label13 = uilabel(app.PanMNA);
            app.Label13.Text = 'Elétrico';
            app.DropEletrico = uidropdown(app.PanMNA);
            app.DropEletrico.Items = {'LIF', 'Zilany'};
            app.DropEletrico.Value = 'LIF';
            app.Label14 = uilabel(app.PanMNA);
            app.Label14.Text = 'Acústico';
            app.DropAcustico = uidropdown(app.PanMNA);
            app.DropAcustico.Items = {'Zilany'};
            app.DropAcustico.Value = 'Zilany';
            app.LabelNumericEditField14 = uilabel(app.PanMNA);
            app.LabelNumericEditField14.Text = 'Num. neurônios';
            app.NumNNeuro = uieditfield(app.PanMNA, 'numeric');
            app.NumNNeuro.ValueChangedFcn = createCallbackFcn(app, @NumNNeuroValueChanged);
            app.NumNNeuro.Value = 100;
            app.But_simulaMNA = uibutton(app.PanMNA, 'push');
            app.But_simulaMNA.ButtonPushedFcn = createCallbackFcn(app, @But_simulaMNAButtonPushed);
            app.But_simulaMNA.Text = 'Simular modelo N.A.';
            app.Label74 = uilabel(app.PanMNA);
            app.Label74.Text = 'Disc. amostral';
            app.Num_dn_freq_amost = uieditfield(app.PanMNA, 'numeric');
            app.Num_dn_freq_amost.ValueChangedFcn = createCallbackFcn(app, @Num_dn_freq_amostValueChanged);
            app.Num_dn_freq_amost.Value = 3;
            app.Label75 = uilabel(app.PanMNA);
            app.Label75.Text = 'Disc. sum. spikes';
            app.Num_dtn_A = uieditfield(app.PanMNA, 'numeric');
            app.Num_dtn_A.ValueChangedFcn = createCallbackFcn(app, @Num_dtn_AValueChanged);
            app.Num_dtn_A.Value = 3;
            app.Label128 = uilabel(app.PanMNA);
            app.Label128.Text = 'Dist. eletr. [mm]';
            app.Num_dx_eletrodo = uieditfield(app.PanMNA, 'numeric');
            app.Num_dx_eletrodo.ValueChangedFcn = createCallbackFcn(app, @Num_dx_eletrodoValueChanged);
            app.Num_dx_eletrodo.Value = 1;
            app.Label129 = uilabel(app.PanMNA);
            app.Label129.Text = 'Pos. inicial [mm]';
            app.Num_pos_inicial = uieditfield(app.PanMNA, 'numeric');
            app.Num_pos_inicial.ValueChangedFcn = createCallbackFcn(app, @Num_pos_inicialValueChanged);
            app.Lamp_MNA = uilamp(app.PanMNA);
            app.props_ModElet = uipanel(app.TabMNA);
            app.props_ModElet.Title = 'Propriedades do modelo elétrico';
            app.props_ModElet.Units = 'pixels';
            app.Label76 = uilabel(app.props_ModElet);
            app.Label76.Text = 'Resistencia ';
            app.Num_R_mem_min = uieditfield(app.props_ModElet, 'numeric');
            app.Num_R_mem_min.ValueChangedFcn = createCallbackFcn(app, @Num_R_mem_minValueChanged);
            app.Num_R_mem_min.Value = 1900000000;
            app.Label77 = uilabel(app.props_ModElet);
            app.Label77.Text = 'Capacitância';
            app.Num_C_mem_min = uieditfield(app.props_ModElet, 'numeric');
            app.Num_C_mem_min.ValueChangedFcn = createCallbackFcn(app, @Num_C_mem_minValueChanged);
            app.Num_C_mem_min.Value = 7e-14;
            app.Label78 = uilabel(app.props_ModElet);
            app.Label78.Text = 'Per. refratário';
            app.Num_dt_refrat_min = uieditfield(app.props_ModElet, 'numeric');
            app.Num_dt_refrat_min.ValueChangedFcn = createCallbackFcn(app, @Num_dt_refrat_minValueChanged);
            app.Num_dt_refrat_min.Value = 0.0003;
            app.Label79 = uilabel(app.props_ModElet);
            app.Label79.Text = 'Pot. limiar';
            app.Num_V_thr_mem_min = uieditfield(app.props_ModElet, 'numeric');
            app.Num_V_thr_mem_min.ValueChangedFcn = createCallbackFcn(app, @Num_V_thr_mem_minValueChanged);
            app.Num_V_thr_mem_min.Value = -0.05;
            app.Label80 = uilabel(app.props_ModElet);
            app.Label80.Text = 'Pot. mem. estac.';
            app.Num_V_rest_mem_min = uieditfield(app.props_ModElet, 'numeric');
            app.Num_V_rest_mem_min.ValueChangedFcn = createCallbackFcn(app, @Num_V_rest_mem_minValueChanged);
            app.Num_V_rest_mem_min.Value = -0.08;
            app.Label81 = uilabel(app.props_ModElet);
            app.Label81.Text = 'Pot. de ruído';
            app.Num_V_ruido_mem_min = uieditfield(app.props_ModElet, 'numeric');
            app.Num_V_ruido_mem_min.ValueChangedFcn = createCallbackFcn(app, @Num_V_ruido_mem_minValueChanged);
            app.Num_V_ruido_mem_min.Value = -0.002;
            app.Num_R_mem_max = uieditfield(app.props_ModElet, 'numeric');
            app.Num_R_mem_max.ValueChangedFcn = createCallbackFcn(app, @Num_R_mem_maxValueChanged);
            app.Num_R_mem_max.Value = 2000000000;
            app.Num_C_mem_max = uieditfield(app.props_ModElet, 'numeric');
            app.Num_C_mem_max.ValueChangedFcn = createCallbackFcn(app, @Num_C_mem_maxValueChanged);
            app.Num_C_mem_max.Value = 5e-13;
            app.Num_dt_refrat_mem_max = uieditfield(app.props_ModElet, 'numeric');
            app.Num_dt_refrat_mem_max.ValueChangedFcn = createCallbackFcn(app, @Num_dt_refrat_mem_maxValueChanged);
            app.Num_dt_refrat_mem_max.Value = 0.0007;
            app.Num_V_thr_mem_max = uieditfield(app.props_ModElet, 'numeric');
            app.Num_V_thr_mem_max.ValueChangedFcn = createCallbackFcn(app, @Num_V_thr_mem_maxValueChanged);
            app.Num_V_thr_mem_max.Value = -0.036;
            app.Num_V_rest_mem_max = uieditfield(app.props_ModElet, 'numeric');
            app.Num_V_rest_mem_max.ValueChangedFcn = createCallbackFcn(app, @Num_V_rest_mem_maxValueChanged);
            app.Num_V_rest_mem_max.Value = -0.055;
            app.Num_V_ruido_mem_max = uieditfield(app.props_ModElet, 'numeric');
            app.Num_V_ruido_mem_max.ValueChangedFcn = createCallbackFcn(app, @Num_V_ruido_mem_maxValueChanged);
            app.Num_V_ruido_mem_max.Value = 0.002;
            app.TextArea2 = uitextarea(app.props_ModElet);
            app.TextArea2.Editable = 'off';
            app.TextArea2.Value = {'As propriedades do modelo elétrico são '; 'definidas como uma distribuição normal '; 'entre os valores mínimo e máximo '; 'definidos nos campos acima.'};
            app.Label82 = uilabel(app.props_ModElet);
            app.Label82.Text = 'Min';
            app.Label83 = uilabel(app.props_ModElet);
            app.Label83.Text = 'Max';
            app.Label19 = uilabel(app.props_ModElet);
            app.Label19.Text = 'Espalhamento';
            app.Drop_tipo_esp_corr = uidropdown(app.props_ModElet);
            app.Drop_tipo_esp_corr.Items = {'Gauss', 'Exponencial'};
            app.Drop_tipo_esp_corr.ValueChangedFcn = createCallbackFcn(app, @Drop_tipo_esp_corrValueChanged, true);
            app.Drop_tipo_esp_corr.Value = 'Gauss';
            app.TabGroup2 = uitabgroup(app.TabMNA);
            app.TabGroup2.Units = 'pixels';
            app.Tab4 = uitab(app.TabGroup2);
            app.Tab4.Units = 'pixels';
            app.Tab4.Title = 'Posição eletrodos';
            app.Label84 = uilabel(app.Tab4);
            app.Label84.Text = '1';
            app.NumPosel1 = uieditfield(app.Tab4, 'numeric');
            app.Label85 = uilabel(app.Tab4);
            app.Label85.Text = '2';
            app.NumPosel2 = uieditfield(app.Tab4, 'numeric');
            app.Label86 = uilabel(app.Tab4);
            app.Label86.Text = '3';
            app.NumPosel3 = uieditfield(app.Tab4, 'numeric');
            app.Label87 = uilabel(app.Tab4);
            app.Label87.Text = '4';
            app.NumPosel4 = uieditfield(app.Tab4, 'numeric');
            app.Label88 = uilabel(app.Tab4);
            app.Label88.Text = '5';
            app.NumPosel5 = uieditfield(app.Tab4, 'numeric');
            app.Label89 = uilabel(app.Tab4);
            app.Label89.Text = '6';
            app.NumPosel6 = uieditfield(app.Tab4, 'numeric');
            app.Label90 = uilabel(app.Tab4);
            app.Label90.Text = '7';
            app.NumPosel7 = uieditfield(app.Tab4, 'numeric');
            app.Label91 = uilabel(app.Tab4);
            app.Label91.Text = '9';
            app.NumPosel8 = uieditfield(app.Tab4, 'numeric');
            app.Label92 = uilabel(app.Tab4);
            app.Label92.Text = '8';
            app.NumPosel9 = uieditfield(app.Tab4, 'numeric');
            app.Label93 = uilabel(app.Tab4);
            app.Label93.Text = '10';
            app.NumPosel10 = uieditfield(app.Tab4, 'numeric');
            app.Label94 = uilabel(app.Tab4);
            app.Label94.Text = '11';
            app.NumPosel11 = uieditfield(app.Tab4, 'numeric');
            app.Label95 = uilabel(app.Tab4);
            app.Label95.Text = '12';
            app.Label96 = uilabel(app.Tab4);
            app.Label96.Text = '13';
            app.Label97 = uilabel(app.Tab4);
            app.Label97.Text = '14';
            app.Label98 = uilabel(app.Tab4);
            app.Label98.Text = '15';
            app.Label99 = uilabel(app.Tab4);
            app.Label99.Text = '16';
            app.Label100 = uilabel(app.Tab4);
            app.Label100.Text = '17';
            app.Label101 = uilabel(app.Tab4);
            app.Label101.Text = '18';
            app.Label102 = uilabel(app.Tab4);
            app.Label102.Text = '20';
            app.Label103 = uilabel(app.Tab4);
            app.Label103.Text = '19';
            app.Label104 = uilabel(app.Tab4);
            app.Label104.Text = '21';
            app.Label105 = uilabel(app.Tab4);
            app.Label105.Text = '22';
            app.NumPosel12 = uieditfield(app.Tab4, 'numeric');
            app.NumPosel13 = uieditfield(app.Tab4, 'numeric');
            app.NumPosel14 = uieditfield(app.Tab4, 'numeric');
            app.NumPosel15 = uieditfield(app.Tab4, 'numeric');
            app.NumPosel16 = uieditfield(app.Tab4, 'numeric');
            app.NumPosel17 = uieditfield(app.Tab4, 'numeric');
            app.NumPosel18 = uieditfield(app.Tab4, 'numeric');
            app.NumPosel20 = uieditfield(app.Tab4, 'numeric');
            app.NumPosel19 = uieditfield(app.Tab4, 'numeric');
            app.NumPosel21 = uieditfield(app.Tab4, 'numeric');
            app.NumPosel22 = uieditfield(app.Tab4, 'numeric');
            app.Tab5 = uitab(app.TabGroup2);
            app.Tab5.Units = 'pixels';
            app.Tab5.Title = 'Impedância';
            app.NumZel12 = uieditfield(app.Tab5, 'numeric');
            app.NumZel13 = uieditfield(app.Tab5, 'numeric');
            app.NumZel14 = uieditfield(app.Tab5, 'numeric');
            app.NumZel15 = uieditfield(app.Tab5, 'numeric');
            app.NumZel16 = uieditfield(app.Tab5, 'numeric');
            app.NumZel17 = uieditfield(app.Tab5, 'numeric');
            app.NumZel18 = uieditfield(app.Tab5, 'numeric');
            app.NumZel19 = uieditfield(app.Tab5, 'numeric');
            app.NumZel20 = uieditfield(app.Tab5, 'numeric');
            app.NumZel21 = uieditfield(app.Tab5, 'numeric');
            app.NumZel22 = uieditfield(app.Tab5, 'numeric');
            app.NumZel1 = uieditfield(app.Tab5, 'numeric');
            app.NumZel1.ValueDisplayFormat = '%.0f';
            app.NumZel2 = uieditfield(app.Tab5, 'numeric');
            app.NumZel2.ValueDisplayFormat = '%.0f';
            app.NumZel3 = uieditfield(app.Tab5, 'numeric');
            app.NumZel3.ValueDisplayFormat = '%.0f';
            app.NumZel4 = uieditfield(app.Tab5, 'numeric');
            app.NumZel4.ValueDisplayFormat = '%.0f';
            app.NumZel5 = uieditfield(app.Tab5, 'numeric');
            app.NumZel5.ValueDisplayFormat = '%.0f';
            app.NumZel6 = uieditfield(app.Tab5, 'numeric');
            app.NumZel6.ValueDisplayFormat = '%.0f';
            app.NumZel7 = uieditfield(app.Tab5, 'numeric');
            app.NumZel7.ValueDisplayFormat = '%.0f';
            app.NumZel8 = uieditfield(app.Tab5, 'numeric');
            app.NumZel8.ValueDisplayFormat = '%.0f';
            app.NumZel9 = uieditfield(app.Tab5, 'numeric');
            app.NumZel9.ValueDisplayFormat = '%.0f';
            app.NumZel10 = uieditfield(app.Tab5, 'numeric');
            app.NumZel10.ValueDisplayFormat = '%.0f';
            app.NumZel11 = uieditfield(app.Tab5, 'numeric');
            app.NumZel11.ValueDisplayFormat = '%.0f';
            app.Label106 = uilabel(app.Tab5);
            app.Label106.Text = '1';
            app.Label107 = uilabel(app.Tab5);
            app.Label107.Text = '2';
            app.Label108 = uilabel(app.Tab5);
            app.Label108.Text = '3';
            app.Label109 = uilabel(app.Tab5);
            app.Label109.Text = '4';
            app.Label110 = uilabel(app.Tab5);
            app.Label110.Text = '5';
            app.Label111 = uilabel(app.Tab5);
            app.Label111.Text = '6';
            app.Label112 = uilabel(app.Tab5);
            app.Label112.Text = '7';
            app.Label113 = uilabel(app.Tab5);
            app.Label113.Text = '9';
            app.Label114 = uilabel(app.Tab5);
            app.Label114.Text = '8';
            app.Label115 = uilabel(app.Tab5);
            app.Label115.Text = '10';
            app.Label116 = uilabel(app.Tab5);
            app.Label116.Text = '11';
            app.Label117 = uilabel(app.Tab5);
            app.Label117.Text = '12';
            app.Label118 = uilabel(app.Tab5);
            app.Label118.Text = '13';
            app.Label119 = uilabel(app.Tab5);
            app.Label119.Text = '14';
            app.Label120 = uilabel(app.Tab5);
            app.Label120.Text = '15';
            app.Label121 = uilabel(app.Tab5);
            app.Label121.Text = '16';
            app.Label122 = uilabel(app.Tab5);
            app.Label122.Text = '17';
            app.Label123 = uilabel(app.Tab5);
            app.Label123.Text = '18';
            app.Label124 = uilabel(app.Tab5);
            app.Label124.Text = '20';
            app.Label125 = uilabel(app.Tab5);
            app.Label125.Text = '19';
            app.Label126 = uilabel(app.Tab5);
            app.Label126.Text = '21';
            app.Label127 = uilabel(app.Tab5);
            app.Label127.Text = '22';
            app.TabVoc = uitab(app.TabGroup);
            app.TabVoc.Units = 'pixels';
            app.TabVoc.Title = 'Vocoders';
            app.Panel6 = uipanel(app.TabVoc);
            app.Panel6.Title = 'Comparações subjetivas';
            app.Panel6.Units = 'pixels';
            app.LabelKnob = uilabel(app.Panel6);
            app.LabelKnob.Enable = 'off';
            app.LabelKnob.Text = 'Volume';
            app.Knob = uiknob(app.Panel6, 'continuous');
            app.Knob.Enable = 'off';
            app.ButtonGroup = uibuttongroup(app.Panel6);
            app.ButtonGroup.Title = 'Sinais audíveis';
            app.ButtonGroup.Units = 'pixels';
            app.ToggleSinal = uitogglebutton(app.ButtonGroup);
            app.ToggleSinal.Text = 'Sinal de entrada';
            app.ToggleSinal.Value = true;
            app.ToggleVocTrad = uitogglebutton(app.ButtonGroup);
            app.ToggleVocTrad.Text = 'Vocoder tradicional';
            app.ToggleNvoc = uitogglebutton(app.ButtonGroup);
            app.ToggleNvoc.Text = 'Neural vocoder';
            app.LampSinal = uilamp(app.ButtonGroup);
            app.LampVocoder = uilamp(app.ButtonGroup);
            app.LampNVocoder = uilamp(app.ButtonGroup);
            app.But_playVocoders = uibutton(app.Panel6, 'push');
            app.But_playVocoders.ButtonPushedFcn = createCallbackFcn(app, @But_playVocodersButtonPushed);
            app.But_playVocoders.Icon = 'playIcon.jpg';
            app.But_playVocoders.Text = 'Play';
            app.Panel7 = uipanel(app.TabVoc);
            app.Panel7.Title = 'Propriedades vocoder';
            app.Panel7.Units = 'pixels';
            app.But_Nvocoder = uibutton(app.Panel7, 'push');
            app.But_Nvocoder.ButtonPushedFcn = createCallbackFcn(app, @But_NvocoderButtonPushed);
            app.But_Nvocoder.Text = 'Simular N-vocoder';
            app.But_VocoderTrad = uibutton(app.Panel7, 'push');
            app.But_VocoderTrad.ButtonPushedFcn = createCallbackFcn(app, @But_VocoderTradButtonPushed);
            app.But_VocoderTrad.Text = 'Simular Vocoder tradicional';
            app.Label130 = uilabel(app.Panel7);
            app.Label130.Text = 'Carrier';
            app.DropDown = uidropdown(app.Panel7);
            app.DropDown.Items = {'Ruido branco', 'Senoidal', 'HC'};
            app.DropDown.Value = 'Ruido branco';
            app.Label131 = uilabel(app.Panel7);
            app.Label131.Text = 'Num. har. HC';
            app.Num_numhar_HC = uieditfield(app.Panel7, 'numeric');
            app.Num_numhar_HC.Value = 240;
            app.Label132 = uilabel(app.Panel7);
            app.Label132.Text = 'Discretização HC';
            app.Num_df_HC = uieditfield(app.Panel7, 'numeric');
            app.Num_df_HC.Value = 100;
            app.Label133 = uilabel(app.Panel7);
            app.Label133.Text = 'Freq. fundamental';
            app.Num_F0_HC = uieditfield(app.Panel7, 'numeric');
            app.Num_F0_HC.Value = 100;
            app.StateButton = uibutton(app.TabVoc, 'state');
            app.TabPlots = uitab(app.TabGroup);
            app.TabPlots.Units = 'pixels';
            app.TabPlots.Title = 'Plots';
            app.UIAxes2 = uiaxes(app.TabPlots);
            title(app.UIAxes2, 'Title');
            xlabel(app.UIAxes2, 'X');
            ylabel(app.UIAxes2, 'Y');
            app.But_plotEletrodograma = uibutton(app.TabPlots, 'push');
            app.But_plotEletrodograma.Text = 'Eletrodograma';
            app.But_plotNeurograma = uibutton(app.TabPlots, 'push');
            app.But_plotNeurograma.Text = 'Neurograma';
            app.But_plotEspectrograma = uibutton(app.TabPlots, 'push');
            app.But_plotEspectrograma.Text = 'Espectrograma';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Units = 'pixels';
            app.Tab3.Title = 'Help';
        end
    end

    methods (Access = public)

        % Construct app
        function app = appSimIC()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end